classdef SpectrApp2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        StoreBrightRefButton      matlab.ui.control.Button
        LoadDarkRefButton         matlab.ui.control.Button
        LoadBrightRefButton       matlab.ui.control.Button
        ClearBrightRefButton      matlab.ui.control.Button
        StoreDarkRefButton        matlab.ui.control.Button
        ClearDarkRefButton        matlab.ui.control.Button
        SaveBrightRefButton       matlab.ui.control.Button
        SaveDarkRefButton         matlab.ui.control.Button
        settingButtonGroup        matlab.ui.container.ButtonGroup
        spname_8                  matlab.ui.control.EditField
        spname_7                  matlab.ui.control.EditField
        spname_6                  matlab.ui.control.EditField
        spname_5                  matlab.ui.control.EditField
        spname_4                  matlab.ui.control.EditField
        spname_3                  matlab.ui.control.EditField
        spname_2                  matlab.ui.control.EditField
        spname_1                  matlab.ui.control.EditField
        Button_8                  matlab.ui.control.RadioButton
        Button_4                  matlab.ui.control.RadioButton
        Button_7                  matlab.ui.control.RadioButton
        Button_6                  matlab.ui.control.RadioButton
        Button_5                  matlab.ui.control.RadioButton
        Button_3                  matlab.ui.control.RadioButton
        Button_2                  matlab.ui.control.RadioButton
        Button_1                  matlab.ui.control.RadioButton
        NumAvgsEditField          matlab.ui.control.NumericEditField
        NumAvgsEditFieldLabel     matlab.ui.control.Label
        Integrationtimemin1000EditField  matlab.ui.control.NumericEditField
        Integrationtimemin1000EditFieldLabel  matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        multiresetButton          matlab.ui.control.Button
        LiveCheckBox              matlab.ui.control.CheckBox
        MultisaveCheckBox         matlab.ui.control.CheckBox
        changedirButton           matlab.ui.control.Button
        ylimEditField_2           matlab.ui.control.NumericEditField
        ylimEditField             matlab.ui.control.NumericEditField
        ylimEditFieldLabel        matlab.ui.control.Label
        SavePathEditField         matlab.ui.control.EditField
        samplenameEditField       matlab.ui.control.EditField
        samplenameEditFieldLabel  matlab.ui.control.Label
        SaveSpectrumButton        matlab.ui.control.Button
        xlimlowEditField_2        matlab.ui.control.NumericEditField
        xlimEditField             matlab.ui.control.NumericEditField
        PlotButton                matlab.ui.control.Button
        xlimEditFieldLabel        matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % spectrapp2 is the spectrapp interface that loads ocean optics HDX. 
    % at the moment it has buggy live plotting above 1 000 000 microseconds
    % in inttime
    % INTEGRATION TIME IS MEASURED IN MICROSECONDS

    properties (Access = private)
        mTimer% Description
    end
    properties (Access = private)
        doingDone% Description
    end

    properties(Access = public)
        scatteringscanON
%         specdev
        oobj
        dvid
        errorCode=0;
    end
    methods (Access = public)
        function saver2(app)

            dt = datetime;
            [h,m,s] = hms(dt);
            hh = sprintf('%02d',h);
            mm = sprintf('%02d',m);
            ss = sprintf('%02d',round(s));
            totname = strcat('multiscan_',hh,mm,ss);
            y = yyyymmdd(dt); y = num2str(y); y = y(3:end);

            savefolder = getappdata(app.UIFigure, 'savefol');
            multiscan = getappdata(app.UIFigure, 'multiscan');
            save( strcat(savefolder,'\',y,'\',totname), 'multiscan')
            multiscan = multiscan(1);
            setappdata(app.UIFigure, 'multiscan', multiscan);
        end
        function saver1(app,tangle)
           inst(selnum).ang = tangle;
             setappdata(app.UIFigure,'inst',inst);
            SaveSpectrumButtonPushed(app,1);
            
        end
        function closeSASA(app)
            UIFigureCloseRequest(app, 1)
        end
        function multisave(app)
            app.MultisaveCheckBox.Value=1;
        end
        function multisaveoff(app)
            app.MultisaveCheckBox.Value=0;
        end
        function getscatdark
        % XXthis function should (assuming goniSA has set scatteringscanON=1)
        % call the dark function to store a multiple scatdark

        end
        function getscatbright
        % XXthis function should (assuming goniSA has set scatteringscanON=1)
        % call the dark function to store a multiple scatdark

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         for i = 1:9
            inst(i).intT = app.Integrationtimemin1000EditField.Value;%1000;
            inst(i).Navg = 1;
            inst(i).Darkref = zeros(2068,1);
            inst(i).Brightref = ones(2068,1);
            
         end

            setappdata(app.UIFigure, 'inst',inst)
            multiscan =    struct('rawspec',[],'procspec',[],'brightref',[],'darkref',[],'intT',[],'Navg',[],'wavelengths',[],'desc',[],'timestamp',[],'name',[],'angle',[]);
            setappdata(app.UIFigure, 'multiscan', multiscan);
%             scatteringscanON=0;
%XX
            defaultpath = 'C:\Users\Rox\OneDrive - University of Bristol\Documents\Spectra';
            setappdata(app.UIFigure, 'savefol',defaultpath)
            cd(defaultpath)
            dt=datetime;
            y = yyyymmdd(dt); y = num2str(y); y = y(3:end);
            mkdir(y)
            cd(strcat(defaultpath,'\',y))

            selbut = app.settingButtonGroup.SelectedObject; %XX
           selnum = str2double(selbut.Text); %XX
            [app.oobj, app.dvid] = initOO2;
            intT = inst(selnum).intT;
           app.oobj.setIntegrationTimeMicros(app.dvid, app.errorCode, intT);
           app.oobj.setScansToAverage(app.dvid, app.errorCode, inst(selnum).Navg);

%             [dev,dvid] = initOO2;   %XX
%             app.specdev = dev;
%             app.dvid = dvid;
%                 inst(selnum).device =specO; %XX
            setappdata(app.UIFigure,'inst',inst);
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
%             errorCode=0;
           try
            %load the data
           
           selbut = app.settingButtonGroup.SelectedObject;
           selnum = str2double(selbut.Text);
           inst = getappdata(app.UIFigure,'inst');
           dspec = inst(selnum).Darkref;
           bspec = inst(selnum).Brightref;
           intT = inst(selnum).intT;%app.Integrationtimemin1000EditField.Value;
%            app.oobj.setIntegrationTimeMicros(app.dvid, app.errorCode, intT);
%             intT = app.Integrationtimemin1000EditField.Value; %XX
%             specI=0; %XX
%             chanI=0; %XX

%             specO= initOO(intT, specI,chanI); %XX
%               specO = inst(selnum).device;
%                 [app.oobj, app.dvid] = initOO2;
%                 deviceId = specO(1).Id;
% %                 oobj = app.oobj;
% %                 specO = app.specdev;
%                 [spectrum, errorCodeOut]  = app.oobj.getSpectrum(app.dvid, app.errorCode);
                
%             wavelengths = invoke(specO, 'getWavelengths', specI,chanI);
%             spectralData = invoke(specO, 'getSpectrum', specI);
%             plot(app.UIAxes,wavelengths, spectralData);
%             specO= initOO(intT, specI,chanI);

%             navg = app.NumAvgsEditField.Value;
%             wavelengths = invoke(specO, 'getWavelengths', specI,chanI);%XX
             [wavelengths, errorCodeOut] = app.oobj.getWavelengths(app.dvid, app.errorCode);
%             multspec = zeros(size(double(wavelengths)',1),navg);
%             size(multspec);

% % %             for i= 1: navg
%             spectralData = invoke(specO, 'getSpectrum', specI);%XX
%             [spectralData, errorCodeOut] = ocean.getSpectrum(deviceId, errorCode);
            [spectrum, errorCodeOut]  = app.oobj.getSpectrum(app.dvid, app.errorCode);
% % %             multspec(:,i) = double(spectrum)';%spectralData;
% % %             end

%             if navg>1
%             ttspec = nanmean(multspec,2);
%             else
            ttspec = double(spectrum)';%pectralData;
%             end

            tspec = (ttspec-dspec)./(bspec);%bspec ALREADY INCLUDES - dspec
% tspec=spectrum;
            plot(app.UIAxes,wavelengths, tspec);
            
            inst(selnum).rawspec = ttspec;
            inst(selnum).procspec = tspec;
            inst(selnum).wl = double(wavelengths)';
          %  inst(selnum).angle = tangle;
             setappdata(app.UIFigure,'inst',inst);
            axis tight
            xlim(app.UIAxes,[app.xlimEditField.Value,app.xlimlowEditField_2.Value])
            yl = ylim(app.UIAxes);
            ylim(app.UIAxes,[0 yl(2)])
            app.ylimEditField.Value=0;
            app.ylimEditField_2.Value=yl(2);
            grid(app.UIAxes,'on')

%             disconnect(specO)
%             delete(specO)
%             clear specO
            
           catch
% % %                if exist('specO')
% % %             disconnect(specO)
% % %             delete(specO)
% % %             clear specO
% % %                end

%                 'error in PlotButtonPushed'
                app.oobj.closeDevice(app.dvid, app.errorCode);
           end
        end

        % Value changed function: Integrationtimemin1000EditField
        function Integrationtimemin1000EditFieldValueChanged(app, event)
           app.LiveCheckBox.Value =0;
           selbut = app.settingButtonGroup.SelectedObject;
           selnum = str2double(selbut.Text);
           inst    = getappdata(app.UIFigure, 'inst');
           inst(selnum).intT = app.Integrationtimemin1000EditField.Value;%max(1000,app.Integrationtimemin1000EditField.Value);
           app.oobj.setIntegrationTimeMicros(app.dvid, app.errorCode, app.Integrationtimemin1000EditField.Value);
           setappdata(app.UIFigure, 'inst', inst);
           app.LiveCheckBox.Value =1;
           
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.LiveCheckBox.Value =0;
            value = app.MultisaveCheckBox.Value;
            
            if value==1

            dt = datetime;
            [h,m,s] = hms(dt);
            hh = sprintf('%02d',h);
            mm = sprintf('%02d',m);
            ss = sprintf('%02d',round(s));
            totname = strcat('multiscan_',hh,mm,ss);
            y = yyyymmdd(dt); y = num2str(y); y = y(3:end);

            savefolder = getappdata(app.UIFigure, 'savefol');
            multiscan = getappdata(app.UIFigure, 'multiscan');
            save( strcat(savefolder,'\',y,'\',totname), 'multiscan')

            end

%             if exist('specO') %XX
%                 disconnect(specO) %XX
%                 delete(specO) %XX
%                 clear specO %XX
%             end
            if exist('app.oobj')
%                 errorCode = 0;
                app.oobj.closeDevice(2,  app.errorCode);
            end
            if ~isempty(app.mTimer)
%                 stop(app.mTimer);
                delete(app.mTimer);
            end
            delete(app)
        end

        % Selection changed function: settingButtonGroup
        function settingButtonGroupSelectionChanged(app, event)
            selbut = app.settingButtonGroup.SelectedObject;
            selnum = str2double(selbut.Text);         
            inst    = getappdata(app.UIFigure, 'inst');

            app.Integrationtimemin1000EditField.Value = inst(selnum).intT; 
            app.NumAvgsEditField.Value = inst(selnum).Navg; 
           app.oobj.setIntegrationTimeMicros(app.dvid, app.errorCode, app.Integrationtimemin1000EditField.Value);
           app.oobj.setScansToAverage(app.dvid, app.errorCode, app.NumAvgsEditField.Value);

        end

        % Value changed function: NumAvgsEditField
        function NumAvgsEditFieldValueChanged(app, event)
           
           selbut = app.settingButtonGroup.SelectedObject;
           selnum = str2double(selbut.Text);
           inst    = getappdata(app.UIFigure, 'inst');
           inst(selnum).Navg = app.NumAvgsEditField.Value;
           app.oobj.setScansToAverage(app.dvid, app.errorCode, app.NumAvgsEditField.Value);
           setappdata(app.UIFigure, 'inst', inst);
        end

        % Button pushed function: SaveDarkRefButton
        function SaveDarkRefButtonPushed(app, event)
           selbut = app.settingButtonGroup.SelectedObject;
           selnum = str2double(selbut.Text);  
                      inst = getappdata(app.UIFigure,'inst');
%             dspec = inst(selnum).Darkref;
          inst(selnum).Darkref = inst(selnum).rawspec;
           setappdata(app.UIFigure, 'inst', inst); 
% %             try
% %             %load the data
% % %            intT=1000;
% %             intT = app.Integrationtimemin1000EditField.Value;
% %             specI=0;
% %             chanI=0;
% % 
% %             specO= initOO(intT, specI,chanI);
% % 
% %             wavelengths = invoke(specO, 'getWavelengths', specI,chanI);
% %             spectralData = invoke(specO, 'getSpectrum', specI);
% % 
% %             plot(app.UIAxes,wavelengths, spectralData);
% % 
% %             axis tight
% %             xlim(app.UIAxes,[app.xlimEditField.Value,app.xlimlowEditField_2.Value])
% %             yl = ylim(app.UIAxes);
% %             ylim(app.UIAxes,[0 yl(2)])
% %             app.ylimEditField.Value=0;
% %             app.ylimEditField_2.Value=yl(2);
% %             grid(app.UIAxes,'on')
% %             
% %             disconnect(specO)
% %             delete(specO)
% %             clear specO
% %            catch
% %                if exist('specO')
% %             disconnect(specO)
% %             delete(specO)
% %             clear specO
% %                end
% %             end
% % %             SCON = getappdata(app.UIFigure, 'scatteringscanON');
% % %             if SCON==0
% %           inst    = getappdata(app.UIFigure, 'inst');    
% %           inst(selnum).Darkref = spectralData;
% %            setappdata(app.UIFigure, 'inst', inst);     
% % %             else
% % %                 % XX here it needs to make an iterating dark ref
% % %                 % measurement and return it to the object properties
% % %                 darkmultiref = getappdata(app.UIFigure, 'darkmultiref');  
% % %                 n = size(darkmultiref,2); % MIGHT BE 1 CHECK
% % %                 darkmultiref(n+1) = spectralData;
% % %                 setappdata(app.UIFigure, 'darkmultiref')
% % %             end
        end

        % Button pushed function: SaveBrightRefButton
        function SaveBrightRefButtonPushed(app, event)
           selbut = app.settingButtonGroup.SelectedObject;
           selnum = str2double(selbut.Text);
           inst = getappdata(app.UIFigure,'inst');
%             dspec = inst(selnum).Darkref;
          inst(selnum).Brightref = inst(selnum).rawspec;
           setappdata(app.UIFigure, 'inst', inst); 

%             intT = app.Integrationtimemin1000EditField.Value;
%             specI=0;
%             chanI=0;
% 
%             specO= initOO(intT, specI,chanI);

%             wavelengths = invoke(specO, 'getWavelengths', specI,chanI);
%             spectralData = invoke(specO, 'getSpectrum', specI);
%             plot(app.UIAxes,wavelengths, spectralData);
%             specO= initOO(intT, specI,chanI);

% %             navg = app.NumAvgsEditField.Value;
% %             wavelengths = invoke(specO, 'getWavelengths', specI,chanI);
% %             multspec = zeros(size(wavelengths,1),navg);
% %             size(multspec)
% % 
% %             for i= 1: navg
% %             spectralData = invoke(specO, 'getSpectrum', specI);
% %             multspec(:,i) = spectralData-dspec;
% %             end
% % 
% %             if navg>1
% %             tspec = nanmean(multspec,2);
% %             else
% %             tspec = spectralData-dspec;
% %             end
% % 
% %             plot(app.UIAxes,wavelengths, tspec);
% % 
% %             axis tight
% %             xlim(app.UIAxes,[app.xlimEditField.Value,app.xlimlowEditField_2.Value])
% %             yl = ylim(app.UIAxes);
% %             ylim(app.UIAxes,[0 yl(2)])
% %             app.ylimEditField.Value=0;
% %             app.ylimEditField_2.Value=yl(2);
% %             grid(app.UIAxes,'on')
% % %             axis tight
% %             
% %             disconnect(specO)
% %             delete(specO)
% %             clear specO
% % %            catch
% % %                if exist('specO')
% % %             disconnect(specO)
% % %             delete(specO)
% % %             clear specO
% % %                end
% % %                        end
% % %         SCON = getappdata(app.UIFigure, 'scatteringscanON');
% % %             if SCON==0
% % 
% %           inst    = getappdata(app.UIFigure, 'inst');    
% %           inst(selnum).Brightref = spectralData;
% %            setappdata(app.UIFigure, 'inst', inst); 
% %            % XX here it needs to make an iterating dark ref
% %                 % measurement and return it to the object properties
% % %             else
% % %                 brightmultiref = getappdata(app.UIFigure, 'brightmultiref');  
% % %                 n = size(brightmultiref,2); % MIGHT BE 1 CHECK
% % %                 brightmultiref(n+1) = spectralData;
% % %                 setappdata(app.UIFigure, 'brightmultiref')
% % %             end
        end

        % Button pushed function: SaveSpectrumButton
        function SaveSpectrumButtonPushed(app, event)
% save a spectrum
            PlotButtonPushed(app, event)

            % get data and make saveable structure
            selbut = app.settingButtonGroup.SelectedObject;
            selnum = str2double(selbut.Text);   
            inst    = getappdata(app.UIFigure, 'inst');   
            savedata.rawspec = inst(selnum).rawspec;
            savedata.procspec = inst(selnum).procspec;
            savedata.brightref = inst(selnum).Brightref;
            savedata.darkref = inst(selnum).Darkref;
            savedata.intT = inst(selnum).intT;
            savedata.Navg = inst(selnum).Navg;
            savedata.wavelengths = inst(selnum).wl;
            savedata.desc = app.samplenameEditField.Value;
            savedata.angle = inst(selnum).ang;
            dt = datetime;

            [h,m,s] = hms(dt);
            hh = sprintf('%02d',h);
            mm = sprintf('%02d',m);
            ss = sprintf('%02d',round(s));
            
            totname = strcat(app.samplenameEditField.Value,'_',hh,mm,ss);

            if isfield(inst(selnum), 'name')
            tname = inst(selnum).name;
            totname = strcat(app.samplenameEditField.Value,'_',inst(selnum).name,'_',hh,mm,ss); %%INCREMENT NAME!
            else
                tname = '';
            end
            savedata.name = tname;
            savedata.timestamp = dt;
            y = yyyymmdd(dt); y = num2str(y); y = y(3:end);
            savefolder = getappdata(app.UIFigure, 'savefol');
%            cd(strcat(savefolder,'\',y));
value = app.MultisaveCheckBox.Value;
            if value==1
                tmultiscan =    getappdata(app.UIFigure, 'multiscan');
%                 mssize = size(getappdata(app.UIFigure, 'multiscan'));
                if size(tmultiscan,2)>0%mssize(2)>0%
                    n = size(tmultiscan,2);%mssize;%
                    multiscan = getappdata(app.UIFigure, 'multiscan');
                else
                        n=0;
                        multiscan = savedata;
                end
                multiscan(n+1) = savedata;
                setappdata(app.UIFigure, 'multiscan', multiscan);
            else
                save( strcat(savefolder,'\',y,'\',totname), 'savedata')
            end


            % save to appropriate 
        end

        % Value changed function: SavePathEditField
        function SavePathEditFieldValueChanged(app, event)
            newpath = app.SavePathEditField.Value;
            setappdata(app.UIFigure, 'savefol',newpath)
        end

        % Value changed function: spname_8
        function spname_8ValueChanged(app, event)
             selbut = app.settingButtonGroup.SelectedObject;
             selnum = str2double(selbut.Text);
             name = app.spname_8.Value;
             inst = getappdata(app.UIFigure, 'inst');    
             inst(selnum).name = name;
              setappdata(app.UIFigure, 'inst', inst); 

        end

        % Value changed function: spname_7
        function spname_7ValueChanged(app, event)
             selbut = app.settingButtonGroup.SelectedObject;
             selnum = str2double(selbut.Text); 
             name = app.spname_7.Value;
             inst = getappdata(app.UIFigure, 'inst');    
             inst(selnum).name = name;
             setappdata(app.UIFigure, 'inst', inst); 
        end

        % Value changed function: spname_6
        function spname_6ValueChanged(app, event)
             selbut = app.settingButtonGroup.SelectedObject;
             selnum = str2double(selbut.Text);  
             name = app.spname_6.Value;
             inst = getappdata(app.UIFigure, 'inst');    
             inst(selnum).name = name;
             setappdata(app.UIFigure, 'inst', inst); 
        end

        % Value changed function: spname_5
        function spname_5ValueChanged(app, event)
             selbut = app.settingButtonGroup.SelectedObject;
             selnum = str2double(selbut.Text); 
             name = app.spname_5.Value;
             inst = getappdata(app.UIFigure, 'inst');    
             inst(selnum).name = name;
             setappdata(app.UIFigure, 'inst', inst); 
            
        end

        % Value changed function: spname_4
        function spname_4ValueChanged(app, event)
             selbut = app.settingButtonGroup.SelectedObject;
             selnum = str2double(selbut.Text); 
             name = app.spname_4.Value;
             inst = getappdata(app.UIFigure, 'inst');    
             inst(selnum).name = name;
             setappdata(app.UIFigure, 'inst', inst); 
        end

        % Value changed function: spname_3
        function spname_3ValueChanged(app, event)
             selbut = app.settingButtonGroup.SelectedObject;
             selnum = str2double(selbut.Text); 
             name = app.spname_3.Value;
             inst = getappdata(app.UIFigure, 'inst');    
             inst(selnum).name = name;
             setappdata(app.UIFigure, 'inst', inst); 
        end

        % Value changed function: spname_2
        function spname_2ValueChanged(app, event)
             selbut = app.settingButtonGroup.SelectedObject;
             selnum = str2double(selbut.Text); 
             name = app.spname_2.Value;
             inst = getappdata(app.UIFigure, 'inst');    
             inst(selnum).name = name;
             setappdata(app.UIFigure, 'inst', inst);  
        end

        % Value changed function: spname_1
        function spname_1ValueChanged(app, event)
             selbut = app.settingButtonGroup.SelectedObject;
             selnum = str2double(selbut.Text); 
             name = app.spname_1.Value;
             inst = getappdata(app.UIFigure, 'inst');    
             inst(selnum).name = name;
             setappdata(app.UIFigure, 'inst', inst); 
        end

        % Value changed function: ylimEditField
        function ylimEditFieldValueChanged(app, event)
            ylim(app.UIAxes,[app.ylimEditField.Value,app.ylimEditField_2.Value])
        end

        % Value changed function: ylimEditField_2
        function ylimEditField_2ValueChanged(app, event)
            ylim(app.UIAxes,[app.ylimEditField.Value,app.ylimEditField_2.Value])

        end

        % Value changed function: xlimEditField
        function xlimEditFieldValueChanged(app, event)
            xlim(app.UIAxes,[app.xlimEditField.Value,app.xlimlowEditField_2.Value])

            
        end

        % Value changed function: xlimlowEditField_2
        function xlimlowEditField_2ValueChanged(app, event)
            xlim(app.UIAxes,[app.xlimEditField.Value,app.xlimlowEditField_2.Value])
            
        end

        % Value changing function: SavePathEditField
        function SavePathEditFieldValueChanging(app, event)

        end

        % Button pushed function: changedirButton
        function changedirButtonPushed(app, event)
            fold = getappdata(app.UIFigure, 'savefol');
            dname = uigetdir(fold);
            app.SavePathEditField.Value = dname;
        end

        % Button pushed function: ClearBrightRefButton
        function ClearBrightRefButtonPushed(app, event)
            selbut = app.settingButtonGroup.SelectedObject;
            selnum = str2double(selbut.Text);
            inst = getappdata(app.UIFigure,'inst');
            inst(selnum).Brightref = ones(2068,1);
            setappdata(app.UIFigure, 'inst',inst)
        end

        % Button pushed function: ClearDarkRefButton
        function ClearDarkRefButtonPushed(app, event)
            selbut = app.settingButtonGroup.SelectedObject;
            selnum = str2double(selbut.Text);
            inst = getappdata(app.UIFigure,'inst');
            inst(selnum).Darkref = zeros(2068,1);
            setappdata(app.UIFigure, 'inst',inst)
        end

        % Button pushed function: StoreDarkRefButton
        function StoreDarkRefButtonPushed(app, event)
 % get data and make saveable structure
            selbut = app.settingButtonGroup.SelectedObject;
            selnum = str2double(selbut.Text);  
            inst    = getappdata(app.UIFigure, 'inst');   

            savedata.darkref = inst(selnum).Darkref;
            savedata.intT = inst(selnum).intT;
            savedata.Navg = inst(selnum).Navg;
            savedata.wavelengths = inst(selnum).wl;
            savedata.desc = strcat(app.samplenameEditField.Value,'DARKREF');
            if isfield(inst(selnum), 'name')
            savedata.name = inst(selnum).name;
            end
            savedata.timestamp = datetime;
            y = yyyymmdd(datetime); y = num2str(y); y = y(3:end);
            savefolder = getappdata(app.UIFigure, 'savefol'); 

           
save( strcat(savefolder,'\',y,'\',savedata.desc), 'savedata')


        end

        % Button pushed function: StoreBrightRefButton
        function StoreBrightRefButtonPushed(app, event)
             % get data and make saveable structure
            selbut = app.settingButtonGroup.SelectedObject;
            selnum = str2double(selbut.Text); 
            inst    = getappdata(app.UIFigure, 'inst');   

            savedata.brightref = inst(selnum).Brightref;
            savedata.intT = inst(selnum).intT;
            savedata.Navg = inst(selnum).Navg;
            savedata.wavelengths = inst(selnum).wl;
            savedata.desc = strcat(app.samplenameEditField.Value,'BRIGHTREF');
            if isfield(inst(selnum), 'name')
            savedata.name = inst(selnum).name;
            end
            savedata.timestamp = datetime;
            y = yyyymmdd(datetime); y = num2str(y); y = y(3:end);
            savefolder = getappdata(app.UIFigure, 'savefol'); 
            cd(savefolder)
            mkdir(y)
           
save( strcat(savefolder,'\',y,'\',savedata.desc), 'savedata')
        end

        % Button pushed function: LoadBrightRefButton
        function LoadBrightRefButtonPushed(app, event)
           newbright =  uiimport;
           selbut = app.settingButtonGroup.SelectedObject;
           selnum = str2double(selbut.Text); 
            inst    = getappdata(app.UIFigure, 'inst');   
            inst(selnum).Brightref = newbright;
            setappdata(app.UIFigure, 'inst', inst)

        end

        % Button pushed function: LoadDarkRefButton
        function LoadDarkRefButtonPushed(app, event)
           newdark =  uiimport;
           selbut = app.settingButtonGroup.SelectedObject;
           selnum = str2double(selbut.Text);
            inst    = getappdata(app.UIFigure, 'inst');   
            inst(selnum).Darkref = newdark;
            setappdata(app.UIFigure, 'inst', inst)
        end

        % Value changed function: MultisaveCheckBox
        function MultisaveCheckBoxValueChanged(app, event)
            
            
        end

        % Callback function
        function LiveButtonPushed(app, event)
            
        end

        % Value changed function: LiveCheckBox
        function LiveCheckBoxValueChanged(app, event)
            try
            value = app.LiveCheckBox.Value;
            intT = app.Integrationtimemin1000EditField.Value;
            if value == 1
                % checkbox is checked so reset counter, create and start % timer
                app.Integrationtimemin1000EditField.Value/1000000
                app.mTimer = timer('TimerFcn', @timerCallback, 'ExecutionMode', 'FixedRate','BusyMode','error', 'Period', max(app.Integrationtimemin1000EditField.Value/1000000,0.1));%max(0.1,intT/1000000)); % the actual time should be intT/1000000 but this is impractical adn unneccessary
                start(app.mTimer);
            else
                % checkbox is unchecked so stop timer
                stop(app.mTimer);
                delete(app.mTimer);
            end 
            catch
                            if ~isempty(app.mTimer)
            %                 stop(app.mTimer);
                            delete(app.mTimer);
            end
            end
            % this is the callback for the timer
            function timerCallback(~, ~)
                % update the text area and increment the timer
%                 app.TextArea.Value = num2str(app.mCounter);
%                 app.mCounter = app.mCounter + 1;
%             if app.doingDone==1
                PlotButtonPushed(app,event);

                
%             end
            end

%             todo = P;
%             
%             t = timer('Period',intT,'ExecutionMode','fixedRate','TimerFcn',@(~,~) loop(todo));
%             UIFigureCloseRequest(app, event) = @(~,~) done(app, t);
%             LiveCheckBoxValueChanged = @(~,~) switchToggled(value, t);
%                 function switchToggled(value,t)
%                     if value == 1
%                         start(t);
%                     else
%                         stop(t);
%                     end
%                 end
%                 function loop(todo)
%                     todo;
%                 end
%                 function done(t)
%                     % You need to delete timers, or they will live until you close MATLAB.
%                     stop(t);
%                     delete(t);
%                 end
% 
%             if value ==1
%                 PlotButtonPushed
%             elseif value==0
% 
%             end

        end

        % Button pushed function: multiresetButton
        function multiresetButtonPushed(app, event)
            saver2(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Integrationtimemin1000EditFieldLabel = uilabel(app.LeftPanel);
            app.Integrationtimemin1000EditFieldLabel.Text = {'Integration time'; ' (min 1000) '};
            app.Integrationtimemin1000EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Integrationtimemin1000EditField.ValueChangedFcn = createCallbackFcn(app, @Integrationtimemin1000EditFieldValueChanged, true);
            app.Integrationtimemin1000EditField.Value = 10000;
            app.NumAvgsEditFieldLabel = uilabel(app.LeftPanel);
            app.NumAvgsEditFieldLabel.Text = 'Num Avgs';
            app.NumAvgsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumAvgsEditField.ValueChangedFcn = createCallbackFcn(app, @NumAvgsEditFieldValueChanged, true);
            app.NumAvgsEditField.Value = 1;
            app.settingButtonGroup = uibuttongroup(app.LeftPanel);
            app.settingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @settingButtonGroupSelectionChanged, true);
            app.settingButtonGroup.Title = 'setting ';
            app.Button_1 = uiradiobutton(app.settingButtonGroup);
            app.Button_1.Text = '1';
            app.Button_1.Value = true;
            app.Button_2 = uiradiobutton(app.settingButtonGroup);
            app.Button_2.Text = '2';
            app.Button_3 = uiradiobutton(app.settingButtonGroup);
            app.Button_3.Text = '3';
            app.Button_5 = uiradiobutton(app.settingButtonGroup);
            app.Button_5.Text = '5';
            app.Button_6 = uiradiobutton(app.settingButtonGroup);
            app.Button_6.Text = '6';
            app.Button_7 = uiradiobutton(app.settingButtonGroup);
            app.Button_7.Text = '7';
            app.Button_4 = uiradiobutton(app.settingButtonGroup);
            app.Button_4.Text = '4';
            app.Button_8 = uiradiobutton(app.settingButtonGroup);
            app.Button_8.Text = '8';
            app.spname_1 = uieditfield(app.settingButtonGroup, 'text');
            app.spname_1.ValueChangedFcn = createCallbackFcn(app, @spname_1ValueChanged, true);
            app.spname_2 = uieditfield(app.settingButtonGroup, 'text');
            app.spname_2.ValueChangedFcn = createCallbackFcn(app, @spname_2ValueChanged, true);
            app.spname_3 = uieditfield(app.settingButtonGroup, 'text');
            app.spname_3.ValueChangedFcn = createCallbackFcn(app, @spname_3ValueChanged, true);
            app.spname_4 = uieditfield(app.settingButtonGroup, 'text');
            app.spname_4.ValueChangedFcn = createCallbackFcn(app, @spname_4ValueChanged, true);
            app.spname_5 = uieditfield(app.settingButtonGroup, 'text');
            app.spname_5.ValueChangedFcn = createCallbackFcn(app, @spname_5ValueChanged, true);
            app.spname_6 = uieditfield(app.settingButtonGroup, 'text');
            app.spname_6.ValueChangedFcn = createCallbackFcn(app, @spname_6ValueChanged, true);
            app.spname_7 = uieditfield(app.settingButtonGroup, 'text');
            app.spname_7.ValueChangedFcn = createCallbackFcn(app, @spname_7ValueChanged, true);
            app.spname_8 = uieditfield(app.settingButtonGroup, 'text');
            app.spname_8.ValueChangedFcn = createCallbackFcn(app, @spname_8ValueChanged, true);
            app.SaveDarkRefButton = uibutton(app.LeftPanel, 'push');
            app.SaveDarkRefButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDarkRefButtonPushed, true);
            app.SaveDarkRefButton.Text = 'Save Dark Ref';
            app.SaveBrightRefButton = uibutton(app.LeftPanel, 'push');
            app.SaveBrightRefButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBrightRefButtonPushed, true);
            app.SaveBrightRefButton.Text = 'Save Bright Ref';
            app.ClearDarkRefButton = uibutton(app.LeftPanel, 'push');
            app.ClearDarkRefButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDarkRefButtonPushed, true);
            app.ClearDarkRefButton.Text = 'Clear Dark Ref';
            app.StoreDarkRefButton = uibutton(app.LeftPanel, 'push');
            app.StoreDarkRefButton.ButtonPushedFcn = createCallbackFcn(app, @StoreDarkRefButtonPushed, true);
            app.StoreDarkRefButton.Text = 'Store Dark Ref';
            app.ClearBrightRefButton = uibutton(app.LeftPanel, 'push');
            app.ClearBrightRefButton.ButtonPushedFcn = createCallbackFcn(app, @ClearBrightRefButtonPushed, true);
            app.ClearBrightRefButton.Text = 'Clear Bright Ref';
            app.LoadBrightRefButton = uibutton(app.LeftPanel, 'push');
            app.LoadBrightRefButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBrightRefButtonPushed, true);
            app.LoadBrightRefButton.Text = 'Load Bright Ref';
            app.LoadDarkRefButton = uibutton(app.LeftPanel, 'push');
            app.LoadDarkRefButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDarkRefButtonPushed, true);
            app.LoadDarkRefButton.Text = 'Load Dark Ref';
            app.StoreBrightRefButton = uibutton(app.LeftPanel, 'push');
            app.StoreBrightRefButton.ButtonPushedFcn = createCallbackFcn(app, @StoreBrightRefButtonPushed, true);
            app.StoreBrightRefButton.Text = 'Store Bright Ref';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Optical Reflectance')
            xlabel(app.UIAxes, '\lambda (nm)')
            ylabel(app.UIAxes, 'Spectral Intensity')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.xlimEditFieldLabel = uilabel(app.RightPanel);
            app.xlimEditFieldLabel.Text = 'xlim ';
            app.PlotButton = uibutton(app.RightPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.xlimEditField = uieditfield(app.RightPanel, 'numeric');
            app.xlimEditField.ValueChangedFcn = createCallbackFcn(app, @xlimEditFieldValueChanged, true);
            app.xlimEditField.Value = 250;
            app.xlimlowEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.xlimlowEditField_2.ValueChangedFcn = createCallbackFcn(app, @xlimlowEditField_2ValueChanged, true);
            app.xlimlowEditField_2.Value = 700;
            app.SaveSpectrumButton = uibutton(app.RightPanel, 'push');
            app.SaveSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSpectrumButtonPushed, true);
            app.SaveSpectrumButton.Text = 'Save Spectrum';
            app.samplenameEditFieldLabel = uilabel(app.RightPanel);
            app.samplenameEditFieldLabel.Text = 'sample name';
            app.samplenameEditField = uieditfield(app.RightPanel, 'text');
            app.samplenameEditField.Value = 'sample';
            app.SavePathEditField = uieditfield(app.RightPanel, 'text');
            app.SavePathEditField.ValueChangedFcn = createCallbackFcn(app, @SavePathEditFieldValueChanged, true);
            app.SavePathEditField.ValueChangingFcn = createCallbackFcn(app, @SavePathEditFieldValueChanging, true);
            app.SavePathEditField.Value = 'C:\Users\vg19882\OneDrive - University of Bristol\Documents\Spectra';
            app.ylimEditFieldLabel = uilabel(app.RightPanel);
            app.ylimEditFieldLabel.Text = 'ylim ';
            app.ylimEditField = uieditfield(app.RightPanel, 'numeric');
            app.ylimEditField.ValueChangedFcn = createCallbackFcn(app, @ylimEditFieldValueChanged, true);
            app.ylimEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.ylimEditField_2.ValueChangedFcn = createCallbackFcn(app, @ylimEditField_2ValueChanged, true);
            app.ylimEditField_2.Value = 4000;
            app.changedirButton = uibutton(app.RightPanel, 'push');
            app.changedirButton.ButtonPushedFcn = createCallbackFcn(app, @changedirButtonPushed, true);
            app.changedirButton.Text = 'change dir';
            app.MultisaveCheckBox = uicheckbox(app.RightPanel);
            app.MultisaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultisaveCheckBoxValueChanged, true);
            app.MultisaveCheckBox.Text = 'Multisave';
            app.LiveCheckBox = uicheckbox(app.RightPanel);
            app.LiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @LiveCheckBoxValueChanged, true);
            app.LiveCheckBox.Text = 'Live';
            app.multiresetButton = uibutton(app.RightPanel, 'push');
            app.multiresetButton.ButtonPushedFcn = createCallbackFcn(app, @multiresetButtonPushed, true);
            app.multiresetButton.Text = 'multireset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpectrApp2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end